classdef Asg1_Burjo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AmmarHawariAbdillahA19EC0220NaufalHadiSyukrimaA19EC0272Label  matlab.ui.control.Label
        BlurringDarkeningImageApplicationLabel  matlab.ui.control.Label
        ApplyFilterButton   matlab.ui.control.Button
        Slider              matlab.ui.control.Slider
        FilterButtonGroup   matlab.ui.container.ButtonGroup
        DarkenButton        matlab.ui.control.RadioButton
        BlurButton          matlab.ui.control.RadioButton
        SelectRegionButton  matlab.ui.control.Button
        SaveImageButton     matlab.ui.control.Button
        UploadImageButton   matlab.ui.control.Button
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        uploadImage         % uploaded image by user
        editImage           % Hold the edited image value
        regionSelection     % to hold ROIpoly in region button
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.*', 'Pick an image');
            app.uploadImage = strcat(pathname, filename);
            app.uploadImage=imread(app.uploadImage);
            imshow(app.uploadImage, 'Parent', app.UIAxes);
            imshow(app.uploadImage, 'Parent', app.UIAxes_2);
            app.editImage = app.uploadImage;
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            if app.BlurButton.Value == true
                app.uploadImage = app.editImage;
                bluring = fspecial('average', app.Slider.Value);

                if (size(app.uploadImage,3) == 3)
                    app.editImage = imfilter(app.uploadImage, bluring);
                    imshow(app.editImage, 'Parent', app.UIAxes_2);
                else
                    app.editImage = roifilt2(bluring, app.uploadImage, app.regionSelection);
                    imshow(app.editImage, 'Parent', app.UIAxes_2);
                end    

            elseif app.DarkenButton.Value == true
                app.uploadImage = app.editImage;
                darkening = app.uploadImage;
                darkening(app.regionSelection) = 0;
                darkening = darkening + app.Slider.Value;
                app.editImage = imfuse(app.uploadImage, darkening, 'blend');
                imshow(app.editImage, 'Parent', app.UIAxes_2);

            end     
        end

        % Button pushed function: SelectRegionButton
        function SelectRegionButtonPushed(app, event)
            app.regionSelection = roipoly(getimage(app.UIAxes));
            imshow(app.regionSelection, 'Parent',app.UIAxes_2);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            [~, minIdx] = min(abs (value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            value = event.Source.MajorTicks(minIdx);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            format = {'*.jpg'; '*.jpeg'; '*.png'; '*.tif'; '*.tiff'; '*.bmp'; '*.raw'};
            imwrite(app.editImage, uiputfile(format));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Base Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Result')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.SelectRegionButton = uibutton(app.UIFigure, 'push');
            app.SelectRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionButtonPushed, true);
            app.SelectRegionButton.Text = 'Select Region';
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.Title = 'Filter';
            app.BlurButton = uiradiobutton(app.FilterButtonGroup);
            app.BlurButton.Text = 'Blur';
            app.BlurButton.Value = true;
            app.DarkenButton = uiradiobutton(app.FilterButtonGroup);
            app.DarkenButton.Text = 'Darken';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.BlurringDarkeningImageApplicationLabel = uilabel(app.UIFigure);
            app.BlurringDarkeningImageApplicationLabel.Text = 'Blurring / Darkening Image Application';
            app.AmmarHawariAbdillahA19EC0220NaufalHadiSyukrimaA19EC0272Label = uilabel(app.UIFigure);
            app.AmmarHawariAbdillahA19EC0220NaufalHadiSyukrimaA19EC0272Label.Text = {'Ammar Hawari Abdillah - (A19EC0220)'; 'Naufal Hadi Syukrima - (A19EC0272)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Asg1_Burjo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end